classdef imgdet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        BoardImageTab                matlab.ui.container.Tab
        ColorQueueTextAreaLabel      matlab.ui.control.Label
        ColorQueueTextArea           matlab.ui.control.TextArea
        ImageAxes                    matlab.ui.control.UIAxes
        ImageAxesY                   matlab.ui.control.UIAxes
        ImageAxesR                   matlab.ui.control.UIAxes
        ImageAxesG                   matlab.ui.control.UIAxes
        ImageAxesB                   matlab.ui.control.UIAxes
        ColorThresholdsTab           matlab.ui.container.Tab
        RedThresholdPanel            matlab.ui.container.Panel
        RSpinnerLabel                matlab.ui.control.Label
        RedRSpinner                  matlab.ui.control.Spinner
        GSpinnerLabel                matlab.ui.control.Label
        RedGSpinner                  matlab.ui.control.Spinner
        BSpinnerLabel                matlab.ui.control.Label
        RedBSpinner                  matlab.ui.control.Spinner
        YellowThresholdPanel         matlab.ui.container.Panel
        RSpinner_3Label              matlab.ui.control.Label
        YellowRSpinner               matlab.ui.control.Spinner
        GSpinner_3Label              matlab.ui.control.Label
        YellowGSpinner               matlab.ui.control.Spinner
        BSpinner_3Label              matlab.ui.control.Label
        YellowBSpinner               matlab.ui.control.Spinner
        BlueThresholdPanel           matlab.ui.container.Panel
        RSpinner_4Label              matlab.ui.control.Label
        BlueRSpinner                 matlab.ui.control.Spinner
        GSpinner_4Label              matlab.ui.control.Label
        BlueGSpinner                 matlab.ui.control.Spinner
        BSpinner_4Label              matlab.ui.control.Label
        BlueBSpinner                 matlab.ui.control.Spinner
        GreenThresholdPanel          matlab.ui.container.Panel
        RSpinner_5Label              matlab.ui.control.Label
        GreenRSpinner                matlab.ui.control.Spinner
        GSpinner_5Label              matlab.ui.control.Label
        GreenGSpinner                matlab.ui.control.Spinner
        BSpinner_5Label              matlab.ui.control.Label
        GreenBSpinner                matlab.ui.control.Spinner
        StatusTab                    matlab.ui.container.Tab
        TextArea                     matlab.ui.control.TextArea
        Well1LampLabel               matlab.ui.control.Label
        Well1Lamp                    matlab.ui.control.Lamp
        Well2LampLabel               matlab.ui.control.Label
        Well2Lamp                    matlab.ui.control.Lamp
        Well3LampLabel               matlab.ui.control.Label
        Well3Lamp                    matlab.ui.control.Lamp
        Well4LampLabel               matlab.ui.control.Label
        Well4Lamp                    matlab.ui.control.Lamp
        Well5LampLabel               matlab.ui.control.Label
        Well5Lamp                    matlab.ui.control.Lamp
        Well6LampLabel               matlab.ui.control.Label
        Well6Lamp                    matlab.ui.control.Lamp
        Well7LampLabel               matlab.ui.control.Label
        Well7Lamp                    matlab.ui.control.Lamp
        Well8LampLabel               matlab.ui.control.Label
        Well8Lamp                    matlab.ui.control.Lamp
        Panel2                       matlab.ui.container.Panel
        GreenButton                  matlab.ui.control.Button
        YellowButton                 matlab.ui.control.Button
        RedButton                    matlab.ui.control.Button
        BlueButton                   matlab.ui.control.Button
        CameraSelectDropDown_2Label  matlab.ui.control.Label
        CameraSelectDropDown         matlab.ui.control.DropDown
        RunButton                    matlab.ui.control.Button
        BackgroundButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        cameraChoice = 2;
        cam;
        background;
        current;
        snap;
        msgfig; 
        rThreshold;
        gThreshold;
        yThreshold;
        bThreshold;
        nColors = 4;
        nWells = 8;
        wells;
        state;
        Rmask;
        Gmask;
        Ymask;
        Bmask;
        nearestColor;
        washerAngles;
        MotorPositionActual;
        MotorPositionCmd;
        CmdAngle;
    end
    
    properties (Access = public)
        snapTaken = 0; % Description
    end
    
       methods (Access = private)
        
        function updateGUI(app)
            try
                position = [0 0 app.UIFigure.Position(3:4)];
                imshow('clean.png','Parent',app.ImageAxes...
                      ,'XData',[1 position(3)]...
                      ,'YData', [1 position(4)])
                imshow('Red.png','Parent',app.ImageAxesR...
                      ,'XData',[1 position(3)]...
                      ,'YData', [1 position(4)])
                imshow('Green.png','Parent',app.ImageAxesG...
                      ,'XData',[1 position(3)]...
                      ,'YData', [1 position(4)])
                imshow('Yellow.png','Parent',app.ImageAxesY...
                      ,'XData',[1 position(3)]...
                      ,'YData', [1 position(4)])
                imshow('Blue.png','Parent',app.ImageAxesB...
                      ,'XData',[1 position(3)]...
                      ,'YData', [1 position(4)])
            catch
            end
        end
        
        function binImg = cvtBinaryImg(~,img)
            img2 = img;
            [height,width,~] = size(img);
            for i=1:height
                for j=1:width
                    if (img(i,j,1) > 2) || ...
                       (img(i,j,2) > 2) || ...
                       (img(i,j,3) > 2)
                        
                        %Will Show in Green
                        img2(i,j,:) = [175,200,175];
                    end
                end
            end
            
            binImg = im2bw(img2);
        end
        
        function noBckgrnd = subtractBackground(app,origImg,origBckgrnd)
            noBckgrnd = origImg - origBckgrnd;

            %fix discoloring of image
            bin = cvtBinaryImg(app,noBckgrnd);
            noBckgrnd = origImg.*uint8(bin);
        end
        
        function setupCamera(app)
            cam_list = webcamlist;
            try
                cam_name = cam_list{app.cameraChoice};
            catch
                app.cameraChoice = 1;
                app.CameraSelectDropDown.Value = '1';
                cam_name = cam_list{app.cameraChoice};
            end
            app.cam = webcam(cam_name);
            res = strcmp(app.cam.AvailableResolutions,'640x480');
            app.cam.Resolution = app.cam.AvailableResolutions{res == 1}; %Set resolution to 640x480
            preview(app.cam);
            %Setup bg
            app.snapTaken = 0;
            app.msgfig = msgbox(['Please take a snapshot of the...' ...
                ' background by selecting the ''Background'' button...' ...
                'or by clicking ''OK''.'],...
                'Snapshot','modal');
            waitfor(app.msgfig)
            if app.snapTaken ~= 1
                app.snapTaken = 1;
                app.BackgroundButton.ButtonPushedFcn(app,'ButtonPressed');
            end
%             imshow(app.background);
        end
        
        function updateThresholds(app)
            getCurrentState(app);
        end
        
        function getCurrentState(app)
            %get_current_state find current state of the board
            app.current = snapshot(app.cam);
            createImgStruct(app);
            imwrite(app.state.img.clean,'clean.png');
            dims = app.state.img.dims;
            detectColors(app);
            app.state.masks = {app.Rmask,app.Gmask,app.Ymask,app.Bmask};
            
            %find current board state
            for i = 1:app.nColors
                  colored = app.state.img.ori .* uint8(app.state.masks{i});
                  imwrite(colored,sprintf("%s.png",app.state.key{i+1,2}));
            %      figure();
            %      imshow(colored);
                stats = regionprops(app.state.masks{i});
                for j = 1:size(stats)
                    angle = getAngle(app,[dims(4),dims(5)],stats(j).Centroid);
            %         fprintf("Color %s at angle = %0.4f\n",state.key{i+1,2},angle);
                    for k = 1:length(app.state.wells)
                        lower = (angle - 360/(length(app.state.wells)*2));
                        upper = (angle + 360/(length(app.state.wells)*2));
                        if ((app.state.wellLocs(k) >= lower) && ...
                           (app.state.wellLocs(k) <= upper))
                             app.state.wells(k) = i;
                             app.washerAngles(k) = angle;
                        end
                    end
                end
            end
            updateGUI(app);
        end
        
        function createImgStruct(app)
            SE = strel('disk',10);
            
            imgStruct.ori = app.current;
            imgStruct.bg  = app.background;
            imgStruct.sub = subtractBackground(app,imgStruct.ori,imgStruct.bg);
            imgStruct.bin = cvtBinaryImg(app,imgStruct.sub);
            imgStruct.erode  = imerode(imgStruct.bin,SE);
            imgStruct.clean  = uint8(imdilate(imgStruct.erode,SE)) .* imgStruct.ori;
            imgStruct.cbin   = cvtBinaryImg(app,imgStruct.clean);
            [height,width,depth] = size(imgStruct.ori);
            center = [width/2,height/2];
            imgStruct.dims = [height,width,depth,center];
            
            app.state.img = imgStruct;
            
            [app.state.angles, app.state.relAngles] = ...
                getAllAngles(app,app.state.img.cbin);
            % gameState.stats = regionprops(gameState.img.cbin,'all');
            
            
            app.state.key = {'0','Empty';'1','Red';'2','Green';...
                             '3','Yellow';'4','Blue'};
            app.state.wellColor = zeros(1,app.nWells);
            app.state.wellBins = rad2deg(linspace(0,2*pi,app.nWells*2)) - 180;
            wellLocs = rad2deg(linspace(0,2*pi,app.nWells+1));
            wellLocs = wellLocs(1:app.nWells);
            app.state.wellLocs = wellLocs - (wellLocs > 180)*360;
            app.state.wells = zeros(1,app.nWells);
        end
        
        function detectColors(app)
            image = app.state.img.clean;

            R = image(:,:,1) >= app.rThreshold(1) ...
              & image(:,:,2) < app.rThreshold(2) ...
              & image(:,:,3) < app.rThreshold(3);
            G = image(:,:,1) < app.gThreshold(1)...
              & image(:,:,2) >= app.gThreshold(2)...
              & image(:,:,3) < app.gThreshold(3);
            Y = image(:,:,1) >= app.yThreshold(1)...
              & image(:,:,2) >= app.yThreshold(2)...
              & image(:,:,3) < app.yThreshold(3);
            B = image(:,:,1) < app.bThreshold(1)...
              & image(:,:,2) < app.bThreshold(2)... 
              & image(:,:,3) >= app.bThreshold(3);
            
            %clean up masks
            junksize = 150;
            R = bwareaopen(R,junksize);
            Y = bwareaopen(Y,junksize);
            G = bwareaopen(G,junksize);
            B = bwareaopen(B,junksize);
            
            app.Rmask = imfill(R,'holes');
            app.Gmask = imfill(G,'holes');
            app.Ymask = imfill(Y,'holes');
            app.Bmask = imfill(B,'holes');
            
            % figure();
            % imshow(gameState.img.clean);
            % title("CLEANED");
            % figure();
            % imshow(R);
            % title("RED MASK");
            % figure();
            % imshow(G);
            % title("GREEN MASK");
            % figure();
            % imshow(B);
            % title("BLUE MASK");
            % figure();
            % imshow(Y);
            % title("YELLOW MASK");
        end
        
        function printState(app,version)
            if (version == 1)
                for i = 1:length(app.state.wells)
                    fprintf("Well %0.0f contains the color %s\n",i,...
                        app.state.key{app.state.wells(i)+1,2});
                end
            else
                figure();
                imshow(app.state.img.clean,'Parent',app.ImageAxes);
                hold on;
                height = app.state.img.dims(2);
                width = app.state.img.dims(1);
                for i = 1:4
                    stats = regionprops(app.state.masks{i});
                    for j = 1:size(stats)
                        center = [stats(j).Centroid(1)-height/2 ...
                                  width/2-stats(j).Centroid(2)];
                        fprintf("Color %s at position (%0.0f,%0.0f)\n",...
                                app.state.key{i+1,2},...
                                center(1),center(2));
                        plot(height/2,width/2,'w+','MarkerFaceColor',...
                            'w','Parent',app.ImageAxes);
                        plot(stats(j).Centroid(1),stats(j).Centroid(2),...
                             'wO','MarkerFaceColor','w','Parent',app.ImageAxes);
                    end
                end
            end
            updateLamps(app);
        end
        
        function angle = getAngle(~,center,centroid)
             diff = center - centroid;
             
             angleRad = atan2(diff(2),diff(1));
             angle = rad2deg(angleRad);
             angle = round(angle,0);
             angle = angle - 90;
            
             if (angle <= -180)
                 angle = angle + 360;
             elseif (angle > 180)
                 angle = angle - 360;
             end
        end
        
        function [ang,relAngs] = getAllAngles(app,binImg)
            [height,width,~] = size(binImg);
            STATS = regionprops(binImg,'all');
            items = size(STATS);
            relAngs = zeros(items);
            ang = zeros(items);
            for i = 1:items
                ang(i) = getAngle(app,[height/2,width/2],STATS(i).Centroid(1:2));
            end
            for i = 1:items
                for j = 1:items
                    relAngs(i,j) = getRelAngle(app,ang(i),ang(j));
                end
            end
        end
        
        function updateLamps(app)
            for i = 1:app.nWells
                keyVal = app.state.wells(i);
                if strcmp(app.state.key{keyVal+1,2},'Red')
                    app.(['Well' num2str(i) 'Lamp']).Color = 'red';
                elseif strcmp(app.state.key{keyVal+1,2},'Green')
                    app.(['Well' num2str(i) 'Lamp']).Color = 'green';
                elseif strcmp(app.state.key{keyVal+1,2},'Yellow')
                    app.(['Well' num2str(i) 'Lamp']).Color = 'yellow';
                elseif strcmp(app.state.key{keyVal+1,2},'Blue')
                    app.(['Well' num2str(i) 'Lamp']).Color = 'blue';
                else
                    app.(['Well' num2str(i) 'Lamp']).Color = [0.5 0.5 0.5];
                end
            end
        end
        
        function findClosestColor(app)
            color = app.ColorQueueTextArea.Value{2};
            [key,~] = find(strcmp(app.state.key,color));
            key = key - 1;
            app.nearestColor = -1;
            for i = 1:(app.nWells/2)
                j = i-1;
                if app.state.wells(i) == key
                    app.nearestColor = i;
                    break;
                elseif app.state.wells(app.nWells - j) == key
                    app.nearestColor = app.nWells - j;
                    break;
                end
            end
            if app.nearestColor == -1
                strng = sprintf("Color not detected. Select another...\n");
                app.CmdAngle = 0;
            else
                app.CmdAngle = app.state.wellLocs(app.nearestColor);
                strng = sprintf("Color: %s found at angle %0.2f (Well %d)\n",...
                        color,app.state.wellLocs(app.nearestColor),...
                        app.nearestColor);
            end
            showStatus(app,strng);
            positionMotor(app);
        end
        
        function showStatus(app,strng)
            fprintf(strng);
            strng = cellstr(strng);
            app.TextArea.Value{end+1} = strng{1};
        end
        
        function getSimulinkParameters(app)
            app.MotorPositionActual = ...
                app.CmdAngle;
        end
        
        function setSimulinkParameters(app)
            app.MotorPositionCmd = num2str(-app.CmdAngle);
            set_param([bdroot,'/MotorPositionCmd'],...
                      'Value',app.MotorPositionCmd);
            getSimulinkParameters(app);
            pause(2);
%             while (app.MotorPositionActual < (app.CmdAngle + 3)) ||...
%                   (app.MotorPositionActual > (app.CmdAngle - 3))
%                 getSimulinkParameters(app);
%             end
        end
        
        function positionMotor(app)
            setSimulinkParameters(app);
            pause(1);
            app.CmdAngle = 0;
            setSimulinkParameters(app);
        end
        
        function relAngle = getRelAngle(~,a1,a2)
            if (a1 < 0)
                a1 = a1 + 360;
            end
            if (a2 < 0)
                a2 = a2 + 360;
            end
            
            if (a2 > a1)
                relAngle = a2 - a1;
            elseif (a1 >= a2)
                relAngle = a1 - a2;
            end
            
            if (relAngle > 180)
                relAngle = relAngle - 360;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.wells = zeros(1,app.nWells);
            app.washerAngles = zeros(1,app.nWells);
            app.rThreshold = [60, 90, 190];
            app.gThreshold = [80, 50, 100];
            app.yThreshold = [95, 80, 190];
            app.bThreshold = [120, 135,70];
            updateGUI(app)
            setupCamera(app)
        end

        % Button pushed function: RedButton
        function RedButtonPushed(app, event)
            app.ColorQueueTextArea.Value{end+1} = 'Red';
            app.ColorQueueTextArea.ValueChangedFcn(app,event);
        end

        % Button pushed function: GreenButton
        function GreenButtonPushed(app, event)
            app.ColorQueueTextArea.Value{end+1} = 'Green';
            app.ColorQueueTextArea.ValueChangedFcn(app,event);
        end

        % Button pushed function: YellowButton
        function YellowButtonPushed(app, event)
            app.ColorQueueTextArea.Value{end+1} = 'Yellow';
            app.ColorQueueTextArea.ValueChangedFcn(app,event);
        end

        % Button pushed function: BlueButton
        function BlueButtonPushed(app, event)
            app.ColorQueueTextArea.Value{end+1} = 'Blue';
            app.ColorQueueTextArea.ValueChangedFcn(app,event);
        end

        % Value changed function: CameraSelectDropDown
        function CameraSelectDropDownValueChanged(app, event)
            app.cameraChoice = str2double(app.CameraSelectDropDown.Value);
            try
                delete(app.cam)
            catch  
            end
            setupCamera(app)
            updateGUI(app)
        end

        % Value changed function: ColorQueueTextArea
        function ColorQueueTextAreaValueChanged(app, event)
            value = app.ColorQueueTextArea.Value;
            str = sprintf("Color Added: %s, Queue Length: %d\n",...
                          value{end},length(value));
            showStatus(app,str);
%             value = {value{1} value{3:end}};
%             app.ColorQueueTextArea.Value = value;
        end

        % Button pushed function: BackgroundButton
        function BackgroundButtonPushed(app, event)
            str = sprintf("Background snapshot taken...\n");
            showStatus(app,str);
            try
                close(app.msgfig);
            catch
            end
            app.snap = snapshot(app.cam);
            app.snapTaken = 1;
            app.background = app.snap;
        end

        % Value changed function: RedRSpinner
        function RedRSpinnerValueChanged(app, event)
            app.rThreshold(1) = app.RedRSpinner.Value;
            updateThresholds(app);
        end

        % Value changed function: RedGSpinner
        function RedGSpinnerValueChanged(app, event)
            app.rThreshold(2) = app.RedGSpinner.Value;
            updateThresholds(app);
        end

        % Value changed function: RedBSpinner
        function RedBSpinnerValueChanged(app, event)
            app.rThreshold(3) = app.RedBSpinner.Value;
            updateThresholds(app);
        end

        % Value changed function: GreenRSpinner
        function GreenRSpinnerValueChanged(app, event)
            app.gThreshold(1) = app.GreenRSpinner.Value;
            updateThresholds(app);
        end

        % Value changed function: GreenGSpinner
        function GreenGSpinnerValueChanged(app, event)
            app.gThreshold(2) = app.GreenGSpinner.Value;
            updateThresholds(app);
        end

        % Value changed function: GreenBSpinner
        function GreenBSpinnerValueChanged(app, event)
            app.gThreshold(3) = app.GreenBSpinner.Value;
            updateThresholds(app);
        end

        % Value changed function: YellowGSpinner
        function YellowGSpinnerValueChanged(app, event)
            app.yThreshold(2) = app.YellowGSpinner.Value;
            updateThresholds(app);
        end

        % Value changed function: YellowRSpinner
        function YellowRSpinnerValueChanged(app, event)
            app.yThreshold(1) = app.YellowRSpinner.Value;
            updateThresholds(app);
        end

        % Value changed function: YellowBSpinner
        function YellowBSpinnerValueChanged(app, event)
            app.yThreshold(3) = app.YellowBSpinner.Value;
            updateThresholds(app);
        end

        % Value changed function: BlueGSpinner
        function BlueGSpinnerValueChanged(app, event)
            app.bThreshold(2) = app.BlueGSpinner.Value;
            updateThresholds(app);
        end

        % Value changed function: BlueRSpinner
        function BlueRSpinnerValueChanged(app, event)
            app.bThreshold(1) = app.BlueRSpinner.Value;
            updateThresholds(app);
        end

        % Value changed function: BlueBSpinner
        function BlueBSpinnerValueChanged(app, event)
            app.bThreshold(3) = app.BlueBSpinner.Value;
            updateThresholds(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
               if length(app.ColorQueueTextArea.Value) > 1
                    getCurrentState(app);
                    printState(app,1);
                    findClosestColor(app);
                    app.ColorQueueTextArea.Value = ...
                                    {app.ColorQueueTextArea.Value{1},...
                                      app.ColorQueueTextArea.Value{3:end}};
               else
                    str = sprintf("Empty Queue: Add colors to Queue first!\n");
                    showStatus(app,str);
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BoardImageTab = uitab(app.TabGroup);
            app.BoardImageTab.Title = 'Board Image';
            app.ColorQueueTextAreaLabel = uilabel(app.BoardImageTab);
            app.ColorQueueTextAreaLabel.Text = 'Color Queue';
            app.ColorQueueTextArea = uitextarea(app.BoardImageTab);
            app.ColorQueueTextArea.ValueChangedFcn = createCallbackFcn(app, @ColorQueueTextAreaValueChanged, true);
            app.ImageAxes = uiaxes(app.BoardImageTab);
            title(app.ImageAxes, 'Cleaned Image')
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.ImageAxesY = uiaxes(app.BoardImageTab);
            title(app.ImageAxesY, 'Yellow')
            app.ImageAxesY.XTick = [];
            app.ImageAxesY.XTickLabel = {'[ ]'};
            app.ImageAxesY.YTick = [];
            app.ImageAxesR = uiaxes(app.BoardImageTab);
            title(app.ImageAxesR, 'Red')
            app.ImageAxesR.XTick = [];
            app.ImageAxesR.XTickLabel = {'[ ]'};
            app.ImageAxesR.YTick = [];
            app.ImageAxesG = uiaxes(app.BoardImageTab);
            title(app.ImageAxesG, 'Green')
            app.ImageAxesG.XTick = [];
            app.ImageAxesG.XTickLabel = {'[ ]'};
            app.ImageAxesG.YTick = [];
            app.ImageAxesB = uiaxes(app.BoardImageTab);
            title(app.ImageAxesB, 'Blue')
            app.ImageAxesB.XTick = [];
            app.ImageAxesB.XTickLabel = {'[ ]'};
            app.ImageAxesB.YTick = [];
            app.ColorThresholdsTab = uitab(app.TabGroup);
            app.ColorThresholdsTab.Title = 'Color Thresholds';
            app.RedThresholdPanel = uipanel(app.ColorThresholdsTab);
            app.RedThresholdPanel.Title = 'Red Threshold';
            app.RSpinnerLabel = uilabel(app.RedThresholdPanel);
            app.RSpinnerLabel.Text = 'R';
            app.RedRSpinner = uispinner(app.RedThresholdPanel);
            app.RedRSpinner.Limits = [0 255];
            app.RedRSpinner.ValueChangedFcn = createCallbackFcn(app, @RedRSpinnerValueChanged, true);
            app.RedRSpinner.Value = 66;
            app.GSpinnerLabel = uilabel(app.RedThresholdPanel);
            app.GSpinnerLabel.Text = 'G';
            app.RedGSpinner = uispinner(app.RedThresholdPanel);
            app.RedGSpinner.Limits = [0 255];
            app.RedGSpinner.ValueChangedFcn = createCallbackFcn(app, @RedGSpinnerValueChanged, true);
            app.RedGSpinner.Value = 90;
            app.BSpinnerLabel = uilabel(app.RedThresholdPanel);
            app.BSpinnerLabel.Text = 'B';
            app.RedBSpinner = uispinner(app.RedThresholdPanel);
            app.RedBSpinner.Limits = [0 255];
            app.RedBSpinner.ValueChangedFcn = createCallbackFcn(app, @RedBSpinnerValueChanged, true);
            app.RedBSpinner.Value = 190;
            app.YellowThresholdPanel = uipanel(app.ColorThresholdsTab);
            app.YellowThresholdPanel.Title = 'Yellow Threshold';
            app.RSpinner_3Label = uilabel(app.YellowThresholdPanel);
            app.RSpinner_3Label.Text = 'R';
            app.YellowRSpinner = uispinner(app.YellowThresholdPanel);
            app.YellowRSpinner.Limits = [0 255];
            app.YellowRSpinner.ValueChangedFcn = createCallbackFcn(app, @YellowRSpinnerValueChanged, true);
            app.YellowRSpinner.Value = 95;
            app.GSpinner_3Label = uilabel(app.YellowThresholdPanel);
            app.GSpinner_3Label.Text = 'G';
            app.YellowGSpinner = uispinner(app.YellowThresholdPanel);
            app.YellowGSpinner.Limits = [0 255];
            app.YellowGSpinner.ValueChangedFcn = createCallbackFcn(app, @YellowGSpinnerValueChanged, true);
            app.YellowGSpinner.Value = 80;
            app.BSpinner_3Label = uilabel(app.YellowThresholdPanel);
            app.BSpinner_3Label.Text = 'B';
            app.YellowBSpinner = uispinner(app.YellowThresholdPanel);
            app.YellowBSpinner.Limits = [0 255];
            app.YellowBSpinner.ValueChangedFcn = createCallbackFcn(app, @YellowBSpinnerValueChanged, true);
            app.YellowBSpinner.Value = 190;
            app.BlueThresholdPanel = uipanel(app.ColorThresholdsTab);
            app.BlueThresholdPanel.Title = 'Blue Threshold';
            app.RSpinner_4Label = uilabel(app.BlueThresholdPanel);
            app.RSpinner_4Label.Text = 'R';
            app.BlueRSpinner = uispinner(app.BlueThresholdPanel);
            app.BlueRSpinner.Limits = [0 255];
            app.BlueRSpinner.ValueChangedFcn = createCallbackFcn(app, @BlueRSpinnerValueChanged, true);
            app.BlueRSpinner.Value = 120;
            app.GSpinner_4Label = uilabel(app.BlueThresholdPanel);
            app.GSpinner_4Label.Text = 'G';
            app.BlueGSpinner = uispinner(app.BlueThresholdPanel);
            app.BlueGSpinner.Limits = [0 255];
            app.BlueGSpinner.ValueChangedFcn = createCallbackFcn(app, @BlueGSpinnerValueChanged, true);
            app.BlueGSpinner.Value = 135;
            app.BSpinner_4Label = uilabel(app.BlueThresholdPanel);
            app.BSpinner_4Label.Text = 'B';
            app.BlueBSpinner = uispinner(app.BlueThresholdPanel);
            app.BlueBSpinner.Limits = [0 255];
            app.BlueBSpinner.ValueChangedFcn = createCallbackFcn(app, @BlueBSpinnerValueChanged, true);
            app.BlueBSpinner.Value = 70;
            app.GreenThresholdPanel = uipanel(app.ColorThresholdsTab);
            app.GreenThresholdPanel.Title = 'Green Threshold';
            app.RSpinner_5Label = uilabel(app.GreenThresholdPanel);
            app.RSpinner_5Label.Text = 'R';
            app.GreenRSpinner = uispinner(app.GreenThresholdPanel);
            app.GreenRSpinner.Limits = [0 255];
            app.GreenRSpinner.ValueChangedFcn = createCallbackFcn(app, @GreenRSpinnerValueChanged, true);
            app.GreenRSpinner.Value = 80;
            app.GSpinner_5Label = uilabel(app.GreenThresholdPanel);
            app.GSpinner_5Label.Text = 'G';
            app.GreenGSpinner = uispinner(app.GreenThresholdPanel);
            app.GreenGSpinner.Limits = [0 255];
            app.GreenGSpinner.ValueChangedFcn = createCallbackFcn(app, @GreenGSpinnerValueChanged, true);
            app.GreenGSpinner.Value = 50;
            app.BSpinner_5Label = uilabel(app.GreenThresholdPanel);
            app.BSpinner_5Label.Text = 'B';
            app.GreenBSpinner = uispinner(app.GreenThresholdPanel);
            app.GreenBSpinner.Limits = [0 255];
            app.GreenBSpinner.ValueChangedFcn = createCallbackFcn(app, @GreenBSpinnerValueChanged, true);
            app.GreenBSpinner.Value = 100;
            app.StatusTab = uitab(app.TabGroup);
            app.StatusTab.Title = 'Status';
            app.TextArea = uitextarea(app.StatusTab);
            app.Well1LampLabel = uilabel(app.StatusTab);
            app.Well1LampLabel.Text = 'Well 1';
            app.Well1Lamp = uilamp(app.StatusTab);
            app.Well2LampLabel = uilabel(app.StatusTab);
            app.Well2LampLabel.Text = 'Well 2';
            app.Well2Lamp = uilamp(app.StatusTab);
            app.Well3LampLabel = uilabel(app.StatusTab);
            app.Well3LampLabel.Text = 'Well 3';
            app.Well3Lamp = uilamp(app.StatusTab);
            app.Well4LampLabel = uilabel(app.StatusTab);
            app.Well4LampLabel.Text = 'Well 4';
            app.Well4Lamp = uilamp(app.StatusTab);
            app.Well5LampLabel = uilabel(app.StatusTab);
            app.Well5LampLabel.Text = 'Well 5';
            app.Well5Lamp = uilamp(app.StatusTab);
            app.Well6LampLabel = uilabel(app.StatusTab);
            app.Well6LampLabel.Text = 'Well 6';
            app.Well6Lamp = uilamp(app.StatusTab);
            app.Well7LampLabel = uilabel(app.StatusTab);
            app.Well7LampLabel.Text = 'Well 7';
            app.Well7Lamp = uilamp(app.StatusTab);
            app.Well8LampLabel = uilabel(app.StatusTab);
            app.Well8LampLabel.Text = 'Well 8';
            app.Well8Lamp = uilamp(app.StatusTab);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Color Selection';
            app.GreenButton = uibutton(app.Panel2, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed, true);
            app.GreenButton.Text = 'Green';
            app.YellowButton = uibutton(app.Panel2, 'push');
            app.YellowButton.ButtonPushedFcn = createCallbackFcn(app, @YellowButtonPushed, true);
            app.YellowButton.Text = 'Yellow';
            app.RedButton = uibutton(app.Panel2, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @RedButtonPushed, true);
            app.RedButton.Text = 'Red';
            app.BlueButton = uibutton(app.Panel2, 'push');
            app.BlueButton.ButtonPushedFcn = createCallbackFcn(app, @BlueButtonPushed, true);
            app.BlueButton.Text = 'Blue';
            app.CameraSelectDropDown_2Label = uilabel(app.UIFigure);
            app.CameraSelectDropDown_2Label.Text = 'Camera Select';
            app.CameraSelectDropDown = uidropdown(app.UIFigure);
            app.CameraSelectDropDown.Items = {'1', '2'};
            app.CameraSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraSelectDropDownValueChanged, true);
            app.CameraSelectDropDown.Value = '2';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.BackgroundButton = uibutton(app.UIFigure, 'push');
            app.BackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundButtonPushed, true);
            app.BackgroundButton.Text = 'Background';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imgdet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end